classdef image_enhancement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HistogramDisplayMenu          matlab.ui.container.Menu
        ImageBrighteningMenu          matlab.ui.container.Menu
        ImageNegativeMenu             matlab.ui.container.Menu
        ImageLogTransformationMenu    matlab.ui.container.Menu
        ImagePowerTransformationMenu  matlab.ui.container.Menu
        ImageContrastStretchingMenu   matlab.ui.container.Menu
        HistogramEqualizationMenu     matlab.ui.container.Menu
        HistogramSpecificationMenu    matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        ConfigPanel                   matlab.ui.container.Panel
        ConfigGrid                    matlab.ui.container.GridLayout
        LogArea                       matlab.ui.control.TextArea
        SystemLogLabel                matlab.ui.control.Label
        ExecuteButton                 matlab.ui.control.Button
        ReferenceSelectedImageLabel   matlab.ui.control.Label
        ReferenceImageButton          matlab.ui.control.Button
        InputSelectedImageLabel       matlab.ui.control.Label
        InputImageButton              matlab.ui.control.Button
        Input2ValueEditField          matlab.ui.control.NumericEditField
        Input2ValueEditFieldLabel     matlab.ui.control.Label
        Input1ValueEditField          matlab.ui.control.NumericEditField
        Input1ValueEditFieldLabel     matlab.ui.control.Label
        MakeGrayscaleSwitch           matlab.ui.control.Switch
        MakeGrayscaleSwitchLabel      matlab.ui.control.Label
        SaveResultImageSwitchLabel    matlab.ui.control.Label
        SaveResultImageSwitch         matlab.ui.control.Switch
        ReferenceImageLabel           matlab.ui.control.Label
        InputImageLabel               matlab.ui.control.Label
        Display                       matlab.ui.container.GridLayout
        ImageResultLabel              matlab.ui.control.Label
        ImageReferenceLabel           matlab.ui.control.Label
        ImageSourceLabel              matlab.ui.control.Label
        ImageSource                   matlab.ui.control.Image
        ImageResult                   matlab.ui.control.Image
        ImageReference                matlab.ui.control.Image
        ImageResultUIAxes             matlab.ui.control.UIAxes
        ImageReferenceUIAxes          matlab.ui.control.UIAxes
        ImageSourceUIAxes             matlab.ui.control.UIAxes
    end

    properties (Access = public)
        IMAGE_SOURCE
        IMAGE_SOURCE_NAME
        HIST_DATA_SOURCE
        HIST_DATA_SOURCE_R
        HIST_DATA_SOURCE_G
        HIST_DATA_SOURCE_B
        IMAGE_REFERENCE
        IMAGE_REFERENCE_NAME
        HIST_DATA_REFERENCE
        HIST_DATA_REFERENCE_R
        HIST_DATA_REFERENCE_G
        HIST_DATA_REFERENCE_B
        IMAGE_RESULT
        HIST_DATA_RESULT
        HIST_DATA_RESULT_R
        HIST_DATA_RESULT_G
        HIST_DATA_RESULT_B
        LOG_DATA
        IS_GRAYSCALED
        IS_SAVED
        INPUT_1_VAL = 1
        INPUT_2_VAL = 0
    end

    methods (Access = public)
        
    end
    
    methods (Access = private)
        function display_histogram_in_UIAxes(app, UIAxes, hist_data, hist_data_r, hist_data_g, hist_data_b, is_grayscale, suffix)
            % Clear the axes before plotting new histograms
            cla(UIAxes);

            % Hold the UIAxes to plot multiple histograms on top of each other
            hold(UIAxes, 'on');
            
            x_axis = 0:255;
            % Insert histogram data to UIAxes
            if (not(is_grayscale))
                 bar(UIAxes, x_axis, hist_data_r, 'FaceColor', 'r', 'EdgeColor', 'none','FaceAlpha', 0.2,'DisplayName', 'Color Red');
                 bar(UIAxes, x_axis, hist_data_g, 'FaceColor', 'g', 'EdgeColor', 'none','FaceAlpha', 0.2,'DisplayName', 'Color Green');
                 bar(UIAxes, x_axis, hist_data_b, 'FaceColor', 'b', 'EdgeColor', 'none','FaceAlpha', 0.2,'DisplayName', 'Color Blue');
            else % Grayscale
                bar(UIAxes, x_axis, hist_data, 'FaceColor', 'k', 'EdgeColor', 'none', 'FaceAlpha', 0.2,  'DisplayName', 'Color Gray');
            end

            % Add labels and title
            hist_title = sprintf("Histogram of %s", suffix);
            title(UIAxes, hist_title);
            xlabel(UIAxes, 'Pixel Values');
            ylabel(UIAxes, 'Frequency');

            % Show legend to differentiate between the datasets
            legend(UIAxes, 'show');
            
            % Release the hold
            hold(UIAxes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function browseImage(app)
            
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            addpath("functions\histogram\");
            
            % Open file dialog to browse for an image file
            [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            
            % If the user selected a file, display it in the UIAxes
            if ischar(fileName)
                % Full file path of the selected image
                fullFileName = fullfile(filePath, fileName);

                % Change label
                app.InputSelectedImageLabel.Text = fileName;

                app.IMAGE_SOURCE = imread(fullFileName);
                app.IMAGE_SOURCE_NAME = fileName;

                % Handle if the result is Grayscale
                if (size(app.IMAGE_SOURCE, 3) == 1)
                    grayImage = app.IMAGE_SOURCE;
                    image_source_display = cat(3, grayImage, grayImage, grayImage);
                else
                    image_source_display = app.IMAGE_SOURCE;
                end
                
                % Display the image in the UIAxes
                app.ImageSource.ImageSource = image_source_display; 
                
                % Display the hist in the UIAxes
                [app.HIST_DATA_SOURCE, app.HIST_DATA_SOURCE_R, app.HIST_DATA_SOURCE_G, app.HIST_DATA_SOURCE_B] = histogram_calculate(app.IMAGE_SOURCE);
                display_histogram_in_UIAxes(app, app.ImageSourceUIAxes, app.HIST_DATA_SOURCE, app.HIST_DATA_SOURCE_R, app.HIST_DATA_SOURCE_G, app.HIST_DATA_SOURCE_B, (size(app.IMAGE_SOURCE, 3) == 1), "Initial Image");
               
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            addpath("functions\program\");
            app.LogArea.Value = ""; % Reset System Log
            if isempty(app.IMAGE_SOURCE)
                app.LogArea.Value = app.LogArea.Value + sprintf("[ERROR] Make sure to input an image first!\n");
            else
                valid = true;
                if (app.ConfigPanel.Title == "Image Brightening")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_img_brightening(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED, app.INPUT_1_VAL, app.INPUT_2_VAL);
                elseif (app.ConfigPanel.Title == "Image Negative")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_img_negative(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED);
                elseif (app.ConfigPanel.Title == "Image Log Transformation")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_img_log_transformation(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED, app.INPUT_1_VAL);
                elseif (app.ConfigPanel.Title == "Image Power Transformation")   
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_img_power_transformation(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED, app.INPUT_1_VAL, app.INPUT_2_VAL);
                elseif (app.ConfigPanel.Title == "Image Contrast Stretching")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_img_contrast_stretching(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED);
                elseif (app.ConfigPanel.Title == "Histogram Equalization")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_histogram_equalizer(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED);
                elseif (app.ConfigPanel.Title == "Histogram Specification")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_histogram_specification(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IMAGE_REFERENCE, app.IMAGE_REFERENCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED);
                else
                    valid = false;
                end

                % Check Validity
                if (isempty(app.IMAGE_RESULT) | not(valid))
                    app.LogArea.Value = app.LOG_DATA;
                    app.LogArea.Value = app.LogArea.Value + sprintf("[ERROR] %s is not valid!\n", app.ConfigPanel.Title);
                end

                % Check if it 
                if (valid)
                    % Set Result
                   app.LogArea.Value = app.LogArea.Value + app.LOG_DATA;
    
                    % Handle if the result is Grayscale
                    if (size(app.IMAGE_RESULT, 3) == 1)
                        grayImage = app.IMAGE_RESULT;
                        image_result_display = cat(3, grayImage, grayImage, grayImage);
                    else
                        image_result_display = app.IMAGE_RESULT;
                    end
                    % Display Image
                    app.ImageResult.ImageSource = image_result_display;

                    % Display the hist in the UIAxes
                    display_histogram_in_UIAxes(app, app.ImageResultUIAxes, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, (size(app.IMAGE_RESULT, 3) == 1), "Result Image");
                else

                end
            end
                
        end

        % Value changed function: MakeGrayscaleSwitch
        function MakeGrayscaleSwitchValueChanged(app, event)
            grayscale_switch_val = app.MakeGrayscaleSwitch.Value;
            if (grayscale_switch_val == "On")
                app.IS_GRAYSCALED = 1;
            else
                app.IS_GRAYSCALED = 0;
            end
        end

        % Value changed function: SaveResultImageSwitch
        function SaveResultImageSwitchValueChanged(app, event)
            save_switch_val = app.SaveResultImageSwitch.Value;
            if (save_switch_val == "On")
                app.IS_SAVED = 1;
            else
                app.IS_SAVED = 0;
            end
        end

        % Button pushed function: ReferenceImageButton
        function ReferenceImageButtonPushed(app, event)
            % Open file dialog to browse for an image file
            [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            
            % If the user selected a file, display it in the UIAxes
            if ischar(fileName)
                % Full file path of the selected image
                fullFileName = fullfile(filePath, fileName);

                % Change label
                app.ReferenceSelectedImageLabel.Text = fileName;

                app.IMAGE_REFERENCE = imread(fullFileName);
                app.IMAGE_REFERENCE_NAME = fileName;

                % Handle if the result is Grayscale
                if (size(app.IMAGE_REFERENCE, 3) == 1)
                    grayImage = app.IMAGE_REFERENCE;
                    image_reference_display = cat(3, grayImage, grayImage, grayImage);
                else
                    image_reference_display = app.IMAGE_REFERENCE;
                end
                
                % Display the image in the UIAxes
                app.ImageReference.ImageSource = image_reference_display;  % Display the image

                % Display the hist in the UIAxes
                [app.HIST_DATA_REFERENCE, app.HIST_DATA_REFERENCE_R, app.HIST_DATA_REFERENCE_G, app.HIST_DATA_REFERENCE_B] = histogram_calculate(app.IMAGE_REFERENCE);
                display_histogram_in_UIAxes(app, app.ImageReferenceUIAxes, app.HIST_DATA_REFERENCE, app.HIST_DATA_REFERENCE_R, app.HIST_DATA_REFERENCE_G, app.HIST_DATA_REFERENCE_B, (size(app.IMAGE_REFERENCE, 3) == 1), "Reference Image");
            end
        end

        % Menu selected function: HistogramDisplayMenu
        function HistogramDisplayMenuSelected(app, event)
            app.ConfigPanel.Title = 'Histogram Display';

            app.Input1ValueEditField.Visible = "off";
            app.Input1ValueEditFieldLabel.Visible = "off";
            app.Input2ValueEditField.Visible = "off";
            app.Input2ValueEditFieldLabel.Visible = "off";

            app.MakeGrayscaleSwitch.Visible = "off";
            app.MakeGrayscaleSwitchLabel.Visible = "off";
            app.SaveResultImageSwitch.Visible = "off";
            app.SaveResultImageSwitchLabel.Visible = "off";
            app.ExecuteButton.Visible = "off";

            app.ReferenceImageButton.Visible = "off";
            app.ReferenceImageLabel.Visible = "off";
            app.ReferenceSelectedImageLabel.Visible = "off";

            app.ImageReference.Visible = "off";
            app.ImageReferenceLabel.Visible = "off";
            app.ImageReferenceUIAxes.Visible = "off";

            app.ImageResult.Visible = "off";
            app.ImageResultLabel.Visible = "off";
            app.ImageResultUIAxes.Visible = "off";

            app.Display.ColumnWidth = {'1x', 1, 1};
        end

        % Menu selected function: ImageBrighteningMenu
        function ImageBrighteningMenuSelected(app, event)
            app.ConfigPanel.Title = 'Image Brightening';

            app.Input1ValueEditField.Visible = "on";
            app.Input1ValueEditFieldLabel.Visible = "on";
            app.Input2ValueEditField.Visible = "on";
            app.Input2ValueEditFieldLabel.Visible = "on";

            app.Input1ValueEditField.Value = 1;
            app.Input2ValueEditField.Value = 0;
            app.INPUT_1_VAL = 1;
            app.INPUT_2_VAL = 0;

            app.Input1ValueEditFieldLabel.Text = "A Value";
            app.Input2ValueEditFieldLabel.Text = "B Value";

            app.MakeGrayscaleSwitch.Visible = "on";
            app.MakeGrayscaleSwitchLabel.Visible = "on";
            app.SaveResultImageSwitch.Visible = "on";
            app.SaveResultImageSwitchLabel.Visible = "on";
            app.ExecuteButton.Visible = "on";

            app.ReferenceImageButton.Visible = "off";
            app.ReferenceImageLabel.Visible = "off";
            app.ReferenceSelectedImageLabel.Visible = "off";

            app.ImageReference.Visible = "off";
            app.ImageReferenceLabel.Visible = "off";
            app.ImageReferenceUIAxes.Visible = "off";

            app.ImageResult.Visible = "on";
            app.ImageResultLabel.Visible = "on";
            app.ImageResultUIAxes.Visible = "on";

            app.Display.ColumnWidth = {'1x', 1, '1x'};
        end

        % Menu selected function: ImageNegativeMenu
        function ImageNegativeMenuSelected(app, event)
            app.ConfigPanel.Title = 'Image Negative';

            app.Input1ValueEditField.Visible = "off";
            app.Input1ValueEditFieldLabel.Visible = "off";
            app.Input2ValueEditField.Visible = "off";
            app.Input2ValueEditFieldLabel.Visible = "off";

            app.MakeGrayscaleSwitch.Visible = "on";
            app.MakeGrayscaleSwitchLabel.Visible = "on";
            app.SaveResultImageSwitch.Visible = "on";
            app.SaveResultImageSwitchLabel.Visible = "on";
            app.ExecuteButton.Visible = "on";

            app.ReferenceImageButton.Visible = "off";
            app.ReferenceImageLabel.Visible = "off";
            app.ReferenceSelectedImageLabel.Visible = "off";

            app.ImageReference.Visible = "off";
            app.ImageReferenceLabel.Visible = "off";
            app.ImageReferenceUIAxes.Visible = "off";

            app.ImageResult.Visible = "on";
            app.ImageResultLabel.Visible = "on";
            app.ImageResultUIAxes.Visible = "on";

            app.Display.ColumnWidth = {'1x', 1, '1x'};
        end

        % Menu selected function: ImageLogTransformationMenu
        function ImageLogTransformationMenuSelected(app, event)
            app.ConfigPanel.Title = 'Image Log Transformation';

            app.Input1ValueEditField.Visible = "on";
            app.Input1ValueEditFieldLabel.Visible = "on";
            app.Input2ValueEditField.Visible = "off";
            app.Input2ValueEditFieldLabel.Visible = "off";

            app.Input1ValueEditFieldLabel.Text = "C Value";

            app.Input1ValueEditField.Value = 0;
            app.INPUT_1_VAL = 0;

            app.MakeGrayscaleSwitch.Visible = "on";
            app.MakeGrayscaleSwitchLabel.Visible = "on";
            app.SaveResultImageSwitch.Visible = "on";
            app.SaveResultImageSwitchLabel.Visible = "on";
            app.ExecuteButton.Visible = "on";

            app.ReferenceImageButton.Visible = "off";
            app.ReferenceImageLabel.Visible = "off";
            app.ReferenceSelectedImageLabel.Visible = "off";

            app.ImageReference.Visible = "off";
            app.ImageReferenceLabel.Visible = "off";
            app.ImageReferenceUIAxes.Visible = "off";

            app.ImageResult.Visible = "on";
            app.ImageResultLabel.Visible = "on";
            app.ImageResultUIAxes.Visible = "on";

            app.Display.ColumnWidth = {'1x', 1, '1x'};
        end

        % Menu selected function: ImagePowerTransformationMenu
        function ImagePowerTransformationMenuSelected(app, event)
            app.ConfigPanel.Title = 'Image Power Transformation';

            app.Input1ValueEditField.Visible = "on";
            app.Input1ValueEditFieldLabel.Visible = "on";
            app.Input2ValueEditField.Visible = "on";
            app.Input2ValueEditFieldLabel.Visible = "on";

            app.Input1ValueEditFieldLabel.Text = "C Value";
            app.Input2ValueEditFieldLabel.Text = "Gamma Value";

            app.Input1ValueEditField.Value = 1;
            app.Input2ValueEditField.Value = 0;
            app.INPUT_1_VAL = 1;
            app.INPUT_2_VAL = 0;

            app.MakeGrayscaleSwitch.Visible = "on";
            app.MakeGrayscaleSwitchLabel.Visible = "on";
            app.SaveResultImageSwitch.Visible = "on";
            app.SaveResultImageSwitchLabel.Visible = "on";
            app.ExecuteButton.Visible = "on";

            app.ReferenceImageButton.Visible = "off";
            app.ReferenceImageLabel.Visible = "off";
            app.ReferenceSelectedImageLabel.Visible = "off";

            app.ImageReference.Visible = "off";
            app.ImageReferenceLabel.Visible = "off";
            app.ImageReferenceUIAxes.Visible = "off";

            app.ImageResult.Visible = "on";
            app.ImageResultLabel.Visible = "on";
            app.ImageResultUIAxes.Visible = "on";

            app.Display.ColumnWidth = {'1x', 1, '1x'};
        end

        % Menu selected function: ImageContrastStretchingMenu
        function ImageContrastStretchingMenuSelected(app, event)
            app.ConfigPanel.Title = 'Image Contrast Stretching';

            app.Input1ValueEditField.Visible = "off";
            app.Input1ValueEditFieldLabel.Visible = "off";
            app.Input2ValueEditField.Visible = "off";
            app.Input2ValueEditFieldLabel.Visible = "off";

            app.MakeGrayscaleSwitch.Visible = "on";
            app.MakeGrayscaleSwitchLabel.Visible = "on";
            app.SaveResultImageSwitch.Visible = "on";
            app.SaveResultImageSwitchLabel.Visible = "on";
            app.ExecuteButton.Visible = "on";

            app.ReferenceImageButton.Visible = "off";
            app.ReferenceImageLabel.Visible = "off";
            app.ReferenceSelectedImageLabel.Visible = "off";

            app.ImageReference.Visible = "off";
            app.ImageReferenceLabel.Visible = "off";
            app.ImageReferenceUIAxes.Visible = "off";

            app.ImageResult.Visible = "on";
            app.ImageResultLabel.Visible = "on";
            app.ImageResultUIAxes.Visible = "on";

            app.Display.ColumnWidth = {'1x', 1, '1x'};
        end

        % Menu selected function: HistogramEqualizationMenu
        function HistogramEqualizationMenuSelected(app, event)
            app.ConfigPanel.Title = 'Histogram Equalization';

            app.Input1ValueEditField.Visible = "off";
            app.Input1ValueEditFieldLabel.Visible = "off";
            app.Input2ValueEditField.Visible = "off";
            app.Input2ValueEditFieldLabel.Visible = "off";

            app.MakeGrayscaleSwitch.Visible = "on";
            app.MakeGrayscaleSwitchLabel.Visible = "on";
            app.SaveResultImageSwitch.Visible = "on";
            app.SaveResultImageSwitchLabel.Visible = "on";
            app.ExecuteButton.Visible = "on";

            app.ReferenceImageButton.Visible = "off";
            app.ReferenceImageLabel.Visible = "off";
            app.ReferenceSelectedImageLabel.Visible = "off";

            app.ImageReference.Visible = "off";
            app.ImageReferenceLabel.Visible = "off";
            app.ImageReferenceUIAxes.Visible = "off";

            app.ImageResult.Visible = "on";
            app.ImageResultLabel.Visible = "on";
            app.ImageResultUIAxes.Visible = "on";

            app.Display.ColumnWidth = {'1x', 1, '1x'};
        end

        % Menu selected function: HistogramSpecificationMenu
        function HistogramSpecificationMenuSelected(app, event)
            app.ConfigPanel.Title = 'Histogram Specification';

            app.Input1ValueEditField.Visible = "off";
            app.Input1ValueEditFieldLabel.Visible = "off";
            app.Input2ValueEditField.Visible = "off";
            app.Input2ValueEditFieldLabel.Visible = "off";

            app.MakeGrayscaleSwitch.Visible = "on";
            app.MakeGrayscaleSwitchLabel.Visible = "on";
            app.SaveResultImageSwitch.Visible = "on";
            app.SaveResultImageSwitchLabel.Visible = "on";
            app.ExecuteButton.Visible = "on";

            app.ReferenceImageButton.Visible = "on";
            app.ReferenceImageLabel.Visible = "on";
            app.ReferenceSelectedImageLabel.Visible = "on";

            app.ImageReference.Visible = "on";
            app.ImageReferenceLabel.Visible = "on";
            app.ImageReferenceUIAxes.Visible = "on";

            app.ImageResult.Visible = "on";
            app.ImageResultLabel.Visible = "on";
            app.ImageResultUIAxes.Visible = "on";

            app.Display.ColumnWidth = {'1x', '1x', '1x'};
        end

        % Value changed function: Input1ValueEditField
        function Input1ValueEditFieldValueChanged(app, event)
            app.INPUT_1_VAL = double(app.Input1ValueEditField.Value);
        end

        % Value changed function: Input2ValueEditField
        function Input2ValueEditFieldValueChanged(app, event)
            app.INPUT_2_VAL = double(app.Input2ValueEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramDisplayMenu = uimenu(app.UIFigure);
            app.HistogramDisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramDisplayMenuSelected, true);
            app.HistogramDisplayMenu.Text = 'Histogram Display';
            app.ImageBrighteningMenu = uimenu(app.UIFigure);
            app.ImageBrighteningMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageBrighteningMenuSelected, true);
            app.ImageBrighteningMenu.Text = 'Image Brightening';
            app.ImageNegativeMenu = uimenu(app.UIFigure);
            app.ImageNegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageNegativeMenuSelected, true);
            app.ImageNegativeMenu.Text = 'Image Negative';
            app.ImageLogTransformationMenu = uimenu(app.UIFigure);
            app.ImageLogTransformationMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageLogTransformationMenuSelected, true);
            app.ImageLogTransformationMenu.Text = 'Image Log Transformation';
            app.ImagePowerTransformationMenu = uimenu(app.UIFigure);
            app.ImagePowerTransformationMenu.MenuSelectedFcn = createCallbackFcn(app, @ImagePowerTransformationMenuSelected, true);
            app.ImagePowerTransformationMenu.Text = 'Image Power Transformation';
            app.ImageContrastStretchingMenu = uimenu(app.UIFigure);
            app.ImageContrastStretchingMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageContrastStretchingMenuSelected, true);
            app.ImageContrastStretchingMenu.Text = 'Image Contrast Stretching';
            app.HistogramEqualizationMenu = uimenu(app.UIFigure);
            app.HistogramEqualizationMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramEqualizationMenuSelected, true);
            app.HistogramEqualizationMenu.Text = 'Histogram Equalization';
            app.HistogramSpecificationMenu = uimenu(app.UIFigure);
            app.HistogramSpecificationMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramSpecificationMenuSelected, true);
            app.HistogramSpecificationMenu.Text = 'Histogram Specification';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Display = uigridlayout(app.GridLayout);
            app.ImageSourceUIAxes = uiaxes(app.Display);
            title(app.ImageSourceUIAxes, 'Histogram Title')
            xlabel(app.ImageSourceUIAxes, 'X')
            ylabel(app.ImageSourceUIAxes, 'Y')
            zlabel(app.ImageSourceUIAxes, 'Z')
            app.ImageReferenceUIAxes = uiaxes(app.Display);
            title(app.ImageReferenceUIAxes, 'Histogram Title')
            xlabel(app.ImageReferenceUIAxes, 'X')
            ylabel(app.ImageReferenceUIAxes, 'Y')
            zlabel(app.ImageReferenceUIAxes, 'Z')
            app.ImageReferenceUIAxes.Visible = 'off';
            app.ImageResultUIAxes = uiaxes(app.Display);
            title(app.ImageResultUIAxes, 'Histogram Title')
            xlabel(app.ImageResultUIAxes, 'X')
            ylabel(app.ImageResultUIAxes, 'Y')
            zlabel(app.ImageResultUIAxes, 'Z')
            app.ImageResultUIAxes.Visible = 'off';
            app.ImageReference = uiimage(app.Display);
            app.ImageResult = uiimage(app.Display);
            app.ImageSource = uiimage(app.Display);
            app.ImageSourceLabel = uilabel(app.Display);
            app.ImageSourceLabel.Text = 'Source Image';
            app.ImageReferenceLabel = uilabel(app.Display);
            app.ImageReferenceLabel.Text = 'Reference Image';
            app.ImageResultLabel = uilabel(app.Display);
            app.ImageResultLabel.Text = 'Result Image';
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ConfigPanel.Title = 'Histogram Display';
            app.ConfigGrid = uigridlayout(app.ConfigPanel);
            app.InputImageLabel = uilabel(app.ConfigGrid);
            app.InputImageLabel.Text = 'Input Image';
            app.ReferenceImageLabel = uilabel(app.ConfigGrid);
            app.ReferenceImageLabel.Visible = 'off';
            app.ReferenceImageLabel.Text = 'Reference Image';
            app.SaveResultImageSwitch = uiswitch(app.ConfigGrid, 'slider');
            app.SaveResultImageSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveResultImageSwitchValueChanged, true);
            app.SaveResultImageSwitch.Visible = 'off';
            app.SaveResultImageSwitchLabel = uilabel(app.ConfigGrid);
            app.SaveResultImageSwitchLabel.Visible = 'off';
            app.SaveResultImageSwitchLabel.Text = 'Save Result Image';
            app.MakeGrayscaleSwitchLabel = uilabel(app.ConfigGrid);
            app.MakeGrayscaleSwitchLabel.Visible = 'off';
            app.MakeGrayscaleSwitchLabel.Text = 'Make Grayscale';
            app.MakeGrayscaleSwitch = uiswitch(app.ConfigGrid, 'slider');
            app.MakeGrayscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @MakeGrayscaleSwitchValueChanged, true);
            app.MakeGrayscaleSwitch.Visible = 'off';
            app.Input1ValueEditFieldLabel = uilabel(app.ConfigGrid);
            app.Input1ValueEditFieldLabel.Visible = 'off';
            app.Input1ValueEditFieldLabel.Text = 'Input 1 Value';
            app.Input1ValueEditField = uieditfield(app.ConfigGrid, 'numeric');
            app.Input1ValueEditField.ValueChangedFcn = createCallbackFcn(app, @Input1ValueEditFieldValueChanged, true);
            app.Input1ValueEditField.Visible = 'off';
            app.Input1ValueEditField.Placeholder = '1';
            app.Input1ValueEditField.Value = 1;
            app.Input2ValueEditFieldLabel = uilabel(app.ConfigGrid);
            app.Input2ValueEditFieldLabel.Visible = 'off';
            app.Input2ValueEditFieldLabel.Text = 'Input 2 Value';
            app.Input2ValueEditField = uieditfield(app.ConfigGrid, 'numeric');
            app.Input2ValueEditField.ValueChangedFcn = createCallbackFcn(app, @Input2ValueEditFieldValueChanged, true);
            app.Input2ValueEditField.Visible = 'off';
            app.Input2ValueEditField.Placeholder = '0';
            app.InputImageButton = uibutton(app.ConfigGrid, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Browse...';
            app.InputSelectedImageLabel = uilabel(app.ConfigGrid);
            app.InputSelectedImageLabel.Text = 'No Selected Image';
            app.ReferenceImageButton = uibutton(app.ConfigGrid, 'push');
            app.ReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceImageButtonPushed, true);
            app.ReferenceImageButton.Visible = 'off';
            app.ReferenceImageButton.Text = 'Browse...';
            app.ReferenceSelectedImageLabel = uilabel(app.ConfigGrid);
            app.ReferenceSelectedImageLabel.Visible = 'off';
            app.ReferenceSelectedImageLabel.Text = 'No Selected Image';
            app.ExecuteButton = uibutton(app.ConfigGrid, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Visible = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.SystemLogLabel = uilabel(app.ConfigGrid);
            app.SystemLogLabel.Text = 'System Log';
            app.LogArea = uitextarea(app.ConfigGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_enhancement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @browseImage)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end